classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenreportdocButton             matlab.ui.control.Button
        PlotresultButton                matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SavetoxlsfileButton             matlab.ui.control.Button
        UploadspeedinformationPanel     matlab.ui.container.Panel
        CalculateuploadspeedCheckBox    matlab.ui.control.CheckBox
        UploadspeedinMBsEditField       matlab.ui.control.NumericEditField
        UploadspeedinMBsEditFieldLabel  matlab.ui.control.Label
        ProjectinfoPanel                matlab.ui.container.Panel
        ChooseyourprojecttypeDropDown   matlab.ui.control.DropDown
        ChooseyourprojecttypeDropDownLabel  matlab.ui.control.Label
        LinesofCodeLOCEditField         matlab.ui.control.NumericEditField
        LinesofCodeLOCEditFieldLabel    matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        FindresultsButton               matlab.ui.control.Button
        WelcomeLabel                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindresultsButton
        function FindresultsButtonPushed(app, event)
                KLOC = get(app.LinesofCodeLOCEditField,'Value');
                Type = get(app.ChooseyourprojecttypeDropDown,'Value')
                switch Type
                    case 'organic'
        Type = 1;
                    case 'Embedded'
        Type = 2;
                    case 'Semi-dettached'
        Type = 3;
    otherwise
        error('None chosen?');
                end
                Size_calc = calculateSize(KLOC);
                [effort, duration, TeamSize, KLOC] = cocomoEstimation(KLOC, Type);
                isChecked = app.CalculateuploadspeedCheckBox.Value;
                if isChecked
                    UPSPEED= get(app.UploadspeedinMBsEditField,'Value')
                    [uploadtime, TimMin] = calculateUploadTime(Size_calc, UPSPEED);
                else
                    TimMin = 'Not chosen'
                end


                data = get(app.UITable, 'Data'); 
                data{1, 1} = effort;
                data{1, 2} = duration;
                data{1, 3} = KLOC;
                data{1, 4} = Size_calc;
                data{1, 5} = TeamSize;
                data{1,6} = TimMin;
                set(app.UITable, 'Data', data);

            
        end

        % Button pushed function: PlotresultButton
        function PlotresultButtonPushed(app, event)
% Get data from the UITable
    data = get(app.UITable, 'Data');

    % Extract the File Size (column 4 of the table)
    fileSize = data{1, 4};

    % Validate fileSize is numeric and not empty
    if isnumeric(fileSize) && ~isempty(fileSize)
        ResultPlot(fileSize);  % Call your external function
    else
        uialert(app.UIFigure, 'Please calculate results first.', 'Missing Data');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomeLabel = uilabel(app.UIFigure);
            app.WelcomeLabel.Text = 'Welcome ';
            app.FindresultsButton = uibutton(app.UIFigure, 'push');
            app.FindresultsButton.ButtonPushedFcn = createCallbackFcn(app, @FindresultsButtonPushed, true);
            app.FindresultsButton.Text = 'Find results';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Effort'; 'Duration'; 'KLOC'; 'File_size'; 'TeamSize'; 'Upload speed'};
            app.UITable.RowName = {};
            app.ProjectinfoPanel = uipanel(app.UIFigure);
            app.ProjectinfoPanel.Title = 'Project info ';
            app.LinesofCodeLOCEditFieldLabel = uilabel(app.ProjectinfoPanel);
            app.LinesofCodeLOCEditFieldLabel.Text = 'Lines of Code (LOC):';
            app.LinesofCodeLOCEditField = uieditfield(app.ProjectinfoPanel, 'numeric');
            app.ChooseyourprojecttypeDropDownLabel = uilabel(app.ProjectinfoPanel);
            app.ChooseyourprojecttypeDropDownLabel.Text = 'Choose your project type';
            app.ChooseyourprojecttypeDropDown = uidropdown(app.ProjectinfoPanel);
            app.ChooseyourprojecttypeDropDown.Items = {'organic', 'Embedded', 'Semi-dettached'};
            app.ChooseyourprojecttypeDropDown.Value = 'organic';
            app.UploadspeedinformationPanel = uipanel(app.UIFigure);
            app.UploadspeedinformationPanel.Title = 'Upload speed information';
            app.UploadspeedinMBsEditFieldLabel = uilabel(app.UploadspeedinformationPanel);
            app.UploadspeedinMBsEditFieldLabel.Text = 'Upload speed in MBs';
            app.UploadspeedinMBsEditField = uieditfield(app.UploadspeedinformationPanel, 'numeric');
            app.CalculateuploadspeedCheckBox = uicheckbox(app.UploadspeedinformationPanel);
            app.CalculateuploadspeedCheckBox.Text = 'Calculate upload speed?';
            app.SavetoxlsfileButton = uibutton(app.UIFigure, 'push');
            app.SavetoxlsfileButton.Text = 'Save to xls file';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'A simple Calculator to aid software engineers with estimation  using COCOMO';
            app.PlotresultButton = uibutton(app.UIFigure, 'push');
            app.PlotresultButton.ButtonPushedFcn = createCallbackFcn(app, @PlotresultButtonPushed, true);
            app.PlotresultButton.Text = 'Plot result';
            app.OpenreportdocButton = uibutton(app.UIFigure, 'push');
            app.OpenreportdocButton.Text = 'Open report doc';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end